classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        KeluarButton       matlab.ui.control.Button
        HapusButton        matlab.ui.control.Button
        HasilLabel         matlab.ui.control.Label
        PilihGambarButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            % === Load Model CNN ===
            modelFile = 'cnn_model.mat';
            if ~isfile(modelFile)
                uialert(app.UIFigure, 'Model cnn_model.mat tidak ditemukan.', 'Error');
                return;
            end
            load(modelFile, 'net');
        
            % === Pilih File Gambar ===
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg','File Gambar'}, 'Pilih Gambar');
            if isequal(file,0)
                return;  % User cancel
            end
            fullPath = fullfile(path, file);
            
            % === Baca dan Tampilkan Gambar ===
            img = imread(fullPath);
            axes(app.UIAxes);
            imshow(img, 'Parent', app.UIAxes);
        
            % === Resize sesuai input CNN ===
            inputSize = net.Layers(1).InputSize;  % Misal [100 100 3]
            imgResized = imresize(img, inputSize(1:2));
        
            % === Klasifikasi ===
            [label, score] = classify(net, imgResized);
        
            % === Tampilkan Hasil ===
            hasilStr = sprintf('Hasil: %s (%.2f%%)', string(label), max(score)*100);
            app.HasilLabel.Text = hasilStr;
        end

        % Button pushed function: HapusButton
        function HapusButtonPushed(app, event)
             cla(app.UIAxes);  % Hapus gambar
             app.HasilLabel.Text = 'Hasil: -';  % Reset teks hasil
        end

        % Button pushed function: KeluarButton
        function KeluarButtonPushed(app, event)
            pilihan = uiconfirm(app.UIFigure, ...
                'Apakah Anda yakin ingin menutup aplikasi?', ...
                'Konfirmasi Keluar', ...
                'Options', {'Ya', 'Tidak'}, ...
                'DefaultOption', 'Tidak', ...
                'CancelOption', 'Tidak');
        
            if strcmp(pilihan, 'Ya')
                delete(app);
            else
                % Batalkan penutupan
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gambar')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PilihGambarButton = uibutton(app.UIFigure, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.HasilLabel = uilabel(app.UIFigure);
            app.HasilLabel.Text = 'Hasil:';
            app.HapusButton = uibutton(app.UIFigure, 'push');
            app.HapusButton.ButtonPushedFcn = createCallbackFcn(app, @HapusButtonPushed, true);
            app.HapusButton.Text = 'Hapus';
            app.KeluarButton = uibutton(app.UIFigure, 'push');
            app.KeluarButton.ButtonPushedFcn = createCallbackFcn(app, @KeluarButtonPushed, true);
            app.KeluarButton.Text = 'Keluar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end